classdef cardectoer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        EditField           matlab.ui.control.EditField
        timeEditField       matlab.ui.control.NumericEditField
        timeEditFieldLabel  matlab.ui.control.Label
        exitButton          matlab.ui.control.Button
        selectButton        matlab.ui.control.Button
        Image11             matlab.ui.control.Image
        Image10             matlab.ui.control.Image
        Image9              matlab.ui.control.Image
        Image8              matlab.ui.control.Image
        Image6              matlab.ui.control.Image
        Image5              matlab.ui.control.Image
        Image4              matlab.ui.control.Image
        Image3              matlab.ui.control.Image
        Image2              matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: exitButton
        function exitButtonPushed(app, event)

            delete(app)
        end

        % Callback function: Image10, selectButton
        function selectButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp', '图片文件 (*.jpg, *.png, *.bmp)'}, '选择图片');

            % 如果用户选择了文件
            if ischar(filename)
                % 拼接完整的文件路径
                selectedImage = fullfile(pathname, filename);
                app.Image10.ImageSource = selectedImage;
                c = imread(selectedImage);
                I =c;
                %将图片转为灰度图像
                I1=rgb2gray(c);
                %使用roberts边缘检测方法对灰度图片进行边缘检测 得到二值图像
                I2=edge(I1,'roberts',0.18,'both');
                imwrite(I2,'roberts边缘检测方法对灰度图片进行边缘检测.jpg');
                
                se=[1;1;1];
                %使用形态学操作 去除噪声或不需要的细节。
                I3=imerode(I2,se);
               
                imwrite(I3,'使用形态学操作 去除噪声或不需要的细节.jpg');
                %创造矩形区域
                se=strel('rectangle',[25,25]);
                %图像闭运算 为膨胀操作之后紧跟着的腐蚀操作
                I4=imclose(I3,se);
                imwrite(I4,'图像闭运算 为膨胀操作之后紧跟着的腐蚀操作.jpg');
                %将图像的顶部部分（0.3）的高度设为全黑 排除可能的车牌区域

                [n1, n2] = size(I4);
                I4(1:round(n1/3), 1:n2) = 0;
                %将图像的上下和左右边缘设置为全黑，来排除非车牌区域
                r = floor(n1/10);
                c = floor(n2/10);
                I4(1:r,:)=0;
                I4((9*r):n1,:)=0;
                I4(:,1:c)=0;
                I4(:,(c*9):n2)=0;
                % 使用bwareaopen函数移除小于200个像素的物体，以排除图像中的小噪点
                I5=bwareaopen(I4,200);
                
                [y,x,z]=size(I5);
                myI = double(I5);
                tic
                %用于存储图像中每一行包含蓝色像素的数量。
                Blue_y=zeros(y,1);
                for i=1:y
                    for j=1:x
                        if(myI(i,j,1)==1)
                            Blue_y(i,1)=Blue_y(i,1)+1;
                        end
                    end
                end
                [temp MaxY] = max(Blue_y);
                %PY1=MaxY;: 设置车牌区域的上边界为MaxY。
                PY1=MaxY;

                while((Blue_y(PY1,1)>=5)&&(PY1>1))
                    PY1=PY1-1;
                end
                PY2=MaxY;
                while ((Blue_y(PY2,1)>=5)&&(PY2<y))
                    PY2=PY2+1;
                end
                IY=I(PY1:PY2,:,:);
                % figure,imshow(IY);

                Blue_x=zeros(1,x);
                for j=1:x
                    for i=PY1:PY2
                        if(myI(i,j,1)==1)
                            Blue_x(1,j)=Blue_x(1,j)+1;
                        end
                    end
                end
                % [tempx MaxX] = max(Blue_x);
                %%初始化车牌区域的左边界为1
                PX1=1;
                [Irow,Icol,Idisanwei] = size(I);
                while((Blue_x(1,PX1)<3)&&(PX1<x))
                    PX1=PX1+1;
                end
                %初始化车牌区域的右边界为x
                PX2=x;
                while ((Blue_x(1,PX2)<3)&&(PX2>PX1))
                    PX2=PX2-1;
                end
                PX1=PX1-1;%: 调整车牌区域的左边界。
                PX2=PX2;
                % dw=I(PY1:(PY2-8),PX1:PX2,:);
                dw=I((PY1):(PY2),PX1:PX2,:);
                t=toc;
                app.timeEditField.Value = t*1000;

                if ((PX2-PX1)/(PY2-PY1))>4.6%如果车牌的宽高比超过4.6（可能是为了排除非标准尺寸的车牌），则执行以下操作。
                    PX1=1;
                    [Irow,Icol,Idisanwei] = size(I);
                    while((Blue_x(1,PX1)<11)&&(PX1<x))
                        PX1=PX1+1;
                    end
                    PX2=x;
                    while ((Blue_x(1,PX2)<11)&&(PX2>PX1))
                        PX2=PX2-1;
                    end
                    PX1=PX1-1;
                    PX2=PX2;
                    dw=I((PY1):(PY2),PX1:PX2,:);
                end


                imwrite(dw,'dw.jpg');
                a=imread('dw.jpg');
                app.Image11.ImageSource = a;

                b=rgb2gray(a);
                imwrite(b,'gray licence plate.jpg');

                g_max=double(max(max(b)));
                g_min=double(min(min(b)));
                T=round(g_max-(g_max-g_min)/3);
                [m,n]=size(b);
                d=(double(b)>=T);
                imwrite(d,'binary licence plate.jpg');


                h=fspecial('average',3);
                % 对二值图像 d 执行平均滤波操作，以减少噪声并进一步清晰化车牌区域

                d=im2bw(round(filter2(h,d)));
                imwrite(d,'after average licence plate.jpg');


                se=eye(2);
                [m,n]=size(d);
                if bwarea(d)/m/n>=0.365
                    d=imerode(d,se);
                elseif bwarea(d)/m/n<=0.235
                    d=imdilate(d,se);
                end
                imwrite(d,'expansion or corrosion the licence plate.jpg');
                results = ocr(d);


                [dw1,dw2,dw3]=size(dw);
                fanwei = round(0.0026*dw1*dw2);
                d=bwareaopen(d,fanwei);
                d=qiege(d);
                [m,n]=size(d);
                if (n/m)<4
                    row = m-(n/(4.1));
                    d = d(round(row):m,1:n);
                end

                k1=1;
                k2=1;
                s=sum(d);
                j=1;
                while j~=n
                    while s(j)==0
                        j=j+1;
                    end
                    k1=j;
                    while s(j)~=0&&j<=n-1
                        j=j+1;
                    end
                    k2=j-1;%
                    if k2-k1>=round(n/6.5)%
                        [val,num]=min(sum(d(:,[k1+5:k2-5])));%
                        d(:,k1+num+5)=0;  %
                    end
                end

                % 再切割
                d=qiege(d);


                y1=10;y2=0.25;flag=0;word1=[];
                while flag==0
                    [m,n]=size(d);
                    left=1;wide=0;
                    while sum(d(:,wide+1))~=0
                        wide=wide+1;
                    end

                    if (wide<5)&&(sum(d(:,wide+1))<(10/m))
                        d(:,[1:wide])=0;
                        d=qiege(d);
                    else
                        temp=qiege(imcrop(d,[1 1 wide m]));
                        [m,n]=size(temp);
                        all=sum(sum(temp));
                        two_thirds=sum(sum(temp([round(m/3):2*round(m/3)],:)));
                        if two_thirds/all>y2
                            flag=1;word1=temp;   % WORD 1
                        end
                        d(:,[1:wide])=0;d=qiege(d);
                    end
                end
                [word2,d]=getword(d);
                [word2row,word2col] = size(word2);
                whitesizeofword2 = round(word2row*word2col/12)+1;
                word2=bwareaopen(word2,whitesizeofword2);
                word2=qiege(word2);
                % 分割出第三个字符
                [word3,d]=getword(d);
                % 分割出第四个字符
                [word4,d]=getword(d);
                % 分割出第五个字符
                [word5,d]=getword(d);
                % 分割出第六个字符
                [word6,d]=getword(d);
                [word6row,word6col] = size(word6);
                whitesizeofword6 = round(word6row*word6col/12)+1;
                word6=bwareaopen(word6,whitesizeofword6);
                word6=qiege(word6);
                % 分割出第七个字符
                [word7,d]=getword(d);
                [m,n]=size(word1);

                word1=imresize(word1,[32 16]);

                word2=wordprocess(word2);
                word3=wordprocess(word3);
                word4=wordprocess(word4);
                word5=wordprocess(word5);
                word6=wordprocess(word6);
                word7=wordprocess(word7);


                imwrite(word1,'11.jpg');
                imwrite(word2,'22.jpg');
                imwrite(word3,'33.jpg');
                imwrite(word4,'44.jpg');
                imwrite(word5,'55.jpg');
                imwrite(word6,'66.jpg');
                imwrite(word7,'77.jpg');
                gray_image1= imread("11.jpg");


                gray_image2 = imread("22.jpg");
                gray_image3 = imread("33.jpg");
                gray_image4 = imread("44.jpg");
                gray_image5 = imread("55.jpg");
                gray_image6 = imread("66.jpg");
                gray_image7 = imread("77.jpg");
                a1 = cat(3, gray_image1, gray_image1, gray_image1);
                a2 = cat(3, gray_image2, gray_image2, gray_image2);
                a3 = cat(3, gray_image3, gray_image3, gray_image3);
                a4 = cat(3, gray_image4, gray_image4, gray_image4);
                a5 = cat(3, gray_image5, gray_image5, gray_image5);
                a6 = cat(3, gray_image6, gray_image6, gray_image6);
                a7 = cat(3, gray_image7, gray_image7, gray_image7);
                app.Image2.ImageSource = a1;
                app.Image3.ImageSource = uint8(a2);
                app.Image4.ImageSource = uint8(a3);
                app.Image5.ImageSource = uint8(a4);
                app.Image6.ImageSource = uint8(a5);
                app.Image8.ImageSource = uint8(a6);
                app.Image9.ImageSource = uint8(a7);

                
                app.EditField.Value = results.Text;
                disp(results.Text);


            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image2 = uiimage(app.UIFigure);
            app.Image3 = uiimage(app.UIFigure);
            app.Image4 = uiimage(app.UIFigure);
            app.Image5 = uiimage(app.UIFigure);
            app.Image6 = uiimage(app.UIFigure);
            app.Image8 = uiimage(app.UIFigure);
            app.Image9 = uiimage(app.UIFigure);
            app.Image10 = uiimage(app.UIFigure);
            app.Image10.ImageClickedFcn = createCallbackFcn(app, @selectButtonPushed, true);
            app.Image11 = uiimage(app.UIFigure);
            app.selectButton = uibutton(app.UIFigure, 'push');
            app.selectButton.ButtonPushedFcn = createCallbackFcn(app, @selectButtonPushed, true);
            app.selectButton.Text = 'select';
            app.exitButton = uibutton(app.UIFigure, 'push');
            app.exitButton.ButtonPushedFcn = createCallbackFcn(app, @exitButtonPushed, true);
            app.exitButton.Text = 'exit';
            app.timeEditFieldLabel = uilabel(app.UIFigure);
            app.timeEditFieldLabel.Text = 'time';
            app.timeEditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cardectoer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end